classdef filtros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FILTROSPanel                    matlab.ui.container.Panel
        RuidoButton                     matlab.ui.control.Button
        FiltroButtonGroup               matlab.ui.container.ButtonGroup
        MedianaButton                   matlab.ui.control.RadioButton
        ModaButton                      matlab.ui.control.RadioButton
        MaxyminButton                   matlab.ui.control.RadioButton
        RuidoButtonGroup                matlab.ui.container.ButtonGroup
        SaltandpepperButton             matlab.ui.control.ToggleButton
        Button2_2                       matlab.ui.control.ToggleButton
        Button3_2                       matlab.ui.control.ToggleButton
        ORIGINALLabel                   matlab.ui.control.Label
        RUIDOLabel                      matlab.ui.control.Label
        RESULTADOLabel                  matlab.ui.control.Label
        RuidoImage                      matlab.ui.control.Image
        OriginalImage                   matlab.ui.control.Image
        ImagenesdisponiblesButtonGroup  matlab.ui.container.ButtonGroup
        PeppersButton                   matlab.ui.control.ToggleButton
        FootballButton                  matlab.ui.control.ToggleButton
        MemeButton                      matlab.ui.control.ToggleButton
        ImagenButton                    matlab.ui.control.Button
        RuidoImage_2                    matlab.ui.control.Image
        FiltradoButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        img_original
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RuidoButton
        function RuidoButtonPushed(app, event)
            % Ruido salt and pepper
            if app.SaltandpepperButton.Value
                img_noise=imnoise(app.img_original,'salt & pepper',0.4);
                app.RuidoImage.ImageSource=img_noise;                 
            end
        end

        % Button pushed function: ImagenButton
        function ImagenButtonPushed(app, event)
            if app.PeppersButton.Value
                app.img_original= imread('peppers.png');
            elseif app.FootballButton.Value
                app.img_original= imread('football.jpg');
            elseif app.MemeButton.Value
                app.img_original = imread('meme.jpg');
            end
            app.OriginalImage.ImageSource=app.img_original;                             
            app.RuidoButton.Enable='on';
            app.ImagenButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILTROSPanel = uipanel(app.UIFigure);
            app.FILTROSPanel.Title = 'FILTROS';
            app.RuidoButton = uibutton(app.FILTROSPanel, 'push');
            app.RuidoButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoButtonPushed, true);
            app.RuidoButton.Enable = 'off';
            app.RuidoButton.Text = 'Ruido';
            app.FiltroButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.FiltroButtonGroup.Title = 'Filtro';
            app.MedianaButton = uiradiobutton(app.FiltroButtonGroup);
            app.MedianaButton.Text = 'Mediana';
            app.MedianaButton.Value = true;
            app.ModaButton = uiradiobutton(app.FiltroButtonGroup);
            app.ModaButton.Text = 'Moda';
            app.MaxyminButton = uiradiobutton(app.FiltroButtonGroup);
            app.MaxyminButton.Text = 'Max y min';
            app.RuidoButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.RuidoButtonGroup.Title = 'Ruido';
            app.SaltandpepperButton = uitogglebutton(app.RuidoButtonGroup);
            app.SaltandpepperButton.Text = 'Salt and pepper';
            app.SaltandpepperButton.Value = true;
            app.Button2_2 = uitogglebutton(app.RuidoButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.RuidoButtonGroup);
            app.Button3_2.Text = 'Button3';
            app.ORIGINALLabel = uilabel(app.FILTROSPanel);
            app.ORIGINALLabel.Text = 'ORIGINAL';
            app.RUIDOLabel = uilabel(app.FILTROSPanel);
            app.RUIDOLabel.Text = 'RUIDO';
            app.RESULTADOLabel = uilabel(app.FILTROSPanel);
            app.RESULTADOLabel.Text = 'RESULTADO';
            app.RuidoImage = uiimage(app.FILTROSPanel);
            app.OriginalImage = uiimage(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup.Title = 'Imagenes disponibles';
            app.PeppersButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.PeppersButton.Text = 'Peppers';
            app.PeppersButton.Value = true;
            app.FootballButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.FootballButton.Text = 'Football';
            app.MemeButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.MemeButton.Text = 'Meme';
            app.ImagenButton = uibutton(app.FILTROSPanel, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenButtonPushed, true);
            app.ImagenButton.Text = 'Imagen';
            app.RuidoImage_2 = uiimage(app.FILTROSPanel);
            app.FiltradoButton = uibutton(app.FILTROSPanel, 'push');
            app.FiltradoButton.Text = 'Filtrado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end